classdef TimNghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Nhaphamgx                  matlab.ui.control.EditField
        NhphmgxchophngphplpEditFieldLabel  matlab.ui.control.Label
        Nhapdaoham                 matlab.ui.control.EditField
        NhpohmchophngphptuyntnhEditFieldLabel  matlab.ui.control.Label
        RUN                        matlab.ui.control.Button
        Solanlap                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel        matlab.ui.control.Label
        Ketqua                     matlab.ui.control.NumericEditField
        KtqunghimLabel             matlab.ui.control.Label
        Chonphuongphaptinh         matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel  matlab.ui.control.Label
        Nhapsaiso                  matlab.ui.control.NumericEditField
        NhpsaischophpLabel         matlab.ui.control.Label
        NhapB                      matlab.ui.control.NumericEditField
        NhapA                      matlab.ui.control.NumericEditField
        NhpkhongphnlinghimLabel    matlab.ui.control.Label
        Nhapphuongtrinh            matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel  matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        UIAxes                     matlab.ui.control.UIAxes
    end

   methods (Access = private)

    % --- Hàm hỗ trợ: Vẽ đồ thị ---

function plotFunction(app, f, a_range, b_range, root)
% Tạo vector x cho khoảng vẽ
range_min = min(a_range, b_range) - 0.5;
range_max = max(a_range, b_range) + 0.5;
x = linspace(range_min, range_max, 200);
y = f(x);
% Xóa đồ thị cũ
cla(app.UIAxes);
% Vẽ hàm số
plot(app.UIAxes, x, y, 'LineWidth', 2, 'DisplayName', 'f(x)');
% Giữ đồ thị để vẽ thêm
hold(app.UIAxes, 'on');
% Vẽ trục x (y=0)
yline(app.UIAxes, 0, '--k', 'DisplayName', 'Trục X');
% Đánh dấu nghiệm (nếu có)
if ~isnan(root)
plot(app.UIAxes, root, 0, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'DisplayName', 'Nghiệm');
end
hold(app.UIAxes, 'off');
% Thiết lập nhãn và tiêu đề
app.UIAxes.XLabel.String = 'X';
app.UIAxes.YLabel.String = 'Y';
title(app.UIAxes, 'Đồ thị hàm số');
grid(app.UIAxes, 'on');
legend(app.UIAxes, 'Location', 'best');
end
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUN
        function RUNButtonPushed(app, event)
    try
        %% 1. LẤY DỮ LIỆU
        % Lấy chuỗi phương trình f(x)
        strHam = string(app.Nhapphuongtrinh.Value); 
        
        % Lấy số liệu (xử lý an toàn)
        a = double(app.NhapA.Value); 
        b = double(app.NhapB.Value); 
        tolerance = double(app.Nhapsaiso.Value);
        
        % Lấy chuỗi Đạo hàm và Hàm g(x)
        strHamPrime = string(app.Nhapdaoham.Value);
        strHamG = string(app.Nhaphamgx.Value);
        
        % Lấy phương pháp từ DropDown
        method = string(app.Chonphuongphaptinh.Value);
        
        %% 2. TẠO HÀM SỐ
        % Tự động thêm dấu chấm (.) cho phép toán vector
        strHam = strrep(strHam, '^', '.^'); strHam = strrep(strHam, '*', '.*'); strHam = strrep(strHam, '/', './');
        strHamPrime = strrep(strHamPrime, '^', '.^'); strHamPrime = strrep(strHamPrime, '*', '.*'); strHamPrime = strrep(strHamPrime, '/', './');
        strHamG = strrep(strHamG, '^', '.^'); strHamG = strrep(strHamG, '*', '.*'); strHamG = strrep(strHamG, '/', './');
        
        % Tạo function handle
        try
            f = str2func(['@(x) ' char(strHam)]);
            f_prime = str2func(['@(x) ' char(strHamPrime)]);
            f_g = str2func(['@(x) ' char(strHamG)]); 
        catch
            uialert(app.UIFigure, 'Hàm số, Đạo hàm, hoặc Hàm g(x) nhập lỗi cú pháp!', 'Lỗi');
            return;
        end
        
        %% 3. TÍNH TOÁN (Gọi các hàm giải thuật riêng)
        root = NaN;
        iterations = 0;
        
        % --- SỬ DỤNG CONTAINS ĐỂ SO SÁNH PHƯƠNG PHÁP ---
        if contains(method, 'chia đôi', 'IgnoreCase', true) 
            
            % Kiểm tra điều kiện Chia đôi
            fa = f(a);
            fb = f(b);
            if fa * fb > 0
                uialert(app.UIFigure, 'Chia đôi: f(a) và f(b) phải trái dấu!', 'Lỗi Điều kiện');
                return;
            end
            [root, iterations] = bisectionMethod(app, f, a, b, tolerance);
            
        elseif contains(method, 'tiếp tuyến', 'IgnoreCase', true) || contains(method, 'newton', 'IgnoreCase', true)
            
            initial_guess = (a + b) / 2; % Nghiệm xấp xỉ ban đầu x0
            if isempty(char(strHamPrime))
                 uialert(app.UIFigure, 'Phương pháp Tiếp tuyến yêu cầu nhập Đạo hàm f''(x).', 'Thiếu dữ liệu');
                 return;
            end
            [root, iterations] = newtonRaphsonMethod(app, f, f_prime, initial_guess, tolerance);
            
        elseif contains(method, 'lặp', 'IgnoreCase', true)
            
            initial_guess = (a + b) / 2; % Nghiệm xấp xỉ ban đầu x0
             if isempty(char(strHamG))
                uialert(app.UIFigure, 'Phương pháp Lặp đơn yêu cầu nhập Hàm lặp g(x).', 'Thiếu dữ liệu');
                return;
            end
            [root, iterations] = simpleIterationMethod(app, f_g, initial_guess, tolerance);
            
        else
            uialert(app.UIFigure, ['Chưa chọn đúng phương pháp. Bạn đang chọn: ' char(method)], 'Lỗi DropDown');
            return;
        end
        
        %% 4. HIỂN THỊ KẾT QUẢ VÀ VẼ ĐỒ THỊ
        
        % 4a. Cập nhật kết quả số
        if isnan(root)
             KetQuaStr = 'Lỗi/Không hội tụ';
             SoLanLapStr = num2str(iterations);
        else
             KetQuaStr = sprintf('%.8f', root);
             SoLanLapStr = num2str(iterations);
        end

        % 4b. Tự động nhận diện ô KetQua là số hay chữ để hiển thị
        try
            % Gán giá trị số (nếu KetQua là Numeric Edit Field)
            app.Ketqua.Value = root; 
        catch
            % Gán giá trị chuỗi (nếu Ketqua là Text Edit Field)
            app.Ketqua.Value = KetQuaStr; 
        end
        
        try
            % Gán số lần lặp (nếu Solanlap là Numeric Edit Field)
            app.Solanlap.Value = iterations; 
        catch
            % Gán số lần lặp dưới dạng chuỗi
            app.Solanlap.Value = SoLanLapStr;
        end
        
        % 4c. Vẽ đồ thị
        app.plotFunction(f, a, b, root);
        
    catch e
        % Hiển thị lỗi hệ thống/runtime
        uialert(app.UIFigure, e.message, 'Lỗi Hệ Thống');
    end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TÌM NGHIỆM'};
            app.NhpphngtrnhEditFieldLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình:';
            app.Nhapphuongtrinh = uieditfield(app.UIFigure, 'text');
            app.NhpkhongphnlinghimLabel = uilabel(app.UIFigure);
            app.NhpkhongphnlinghimLabel.Text = 'Nhập khoảng phân li nghiệm:';
            app.NhapA = uieditfield(app.UIFigure, 'numeric');
            app.NhapB = uieditfield(app.UIFigure, 'numeric');
            app.NhpsaischophpLabel = uilabel(app.UIFigure);
            app.NhpsaischophpLabel.Text = 'Nhập sai số cho phép:';
            app.Nhapsaiso = uieditfield(app.UIFigure, 'numeric');
            app.ChnphngphptmDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm:';
            app.Chonphuongphaptinh = uidropdown(app.UIFigure);
            app.Chonphuongphaptinh.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến'};
            app.Chonphuongphaptinh.Value = 'Chia đôi';
            app.KtqunghimLabel = uilabel(app.UIFigure);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm:';
            app.Ketqua = uieditfield(app.UIFigure, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.UIFigure);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp:';
            app.Solanlap = uieditfield(app.UIFigure, 'numeric');
            app.RUN = uibutton(app.UIFigure, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUN.Text = 'RUN';
            app.NhpohmchophngphptuyntnhEditFieldLabel = uilabel(app.UIFigure);
            app.NhpohmchophngphptuyntnhEditFieldLabel.Text = 'Nhập đạo hàm cho phương pháp tuyến tính';
            app.Nhapdaoham = uieditfield(app.UIFigure, 'text');
            app.NhphmgxchophngphplpEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmgxchophngphplpEditFieldLabel.Text = 'Nhập hàm gx cho phương pháp lặp';
            app.Nhaphamgx = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimNghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end