classdef NoiSuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RUNButton                     matlab.ui.control.Button
        Ketquanoisuy                  matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        Nhapgiatrinoisuy              matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        Chonphuongphap                matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapY                         matlab.ui.control.EditField
        NhpYEditFieldLabel            matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        NhapX                         matlab.ui.control.EditField
        NhpXEditFieldLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

   methods (Access = private)
    % --- Phương pháp 1: Chia đôi (Bisection Method) ---
    function [root, iter] = bisectionMethod(app, f, a, b, tol)
        iter = 0;
        maxIter = 1000; 
    
        while (abs(b - a) / 2) > tol && iter < maxIter
            iter = iter + 1;
            c = (a + b) / 2;
            
            if f(c) == 0 
                root = c;
                return;
            end
            
            if f(a) * f(c) < 0
                b = c;
            else
                a = c;
            end
        end
        
        root = (a + b) / 2; 
        if iter >= maxIter
            uialert(app.UIFigure, 'Chia đôi: Không hội tụ trong số lần lặp tối đa.', 'Cảnh báo');
        end
    end

    % --- Phương pháp 2: Tiếp tuyến (Newton-Raphson Method) ---
    function [root, iter] = newtonRaphsonMethod(app, f, f_prime, x0, tol)
        iter = 0;
        maxIter = 1000; 
        x_current = x0;
        
        while iter < maxIter
            fx = f(x_current);
            fpx = f_prime(x_current);
            
            if abs(fx) < tol 
                root = x_current;
                return;
            end
            
            if fpx == 0
                uialert(app.UIFigure, 'Tiếp tuyến: Đạo hàm bằng 0. Chọn điểm xấp xỉ ban đầu khác.', 'Lỗi thuật toán');
                root = NaN;
                return;
            end
            
            x_next = x_current - (fx / fpx);
            
            if abs(x_next - x_current) < tol
                root = x_next;
                return;
            end
            
            x_current = x_next;
            iter = iter + 1;
        end
        
        root = x_current;
        if iter >= maxIter
            uialert(app.UIFigure, 'Tiếp tuyến: Không hội tụ trong số lần lặp tối đa.', 'Cảnh báo');
        end
    end
    % --- Phương pháp 3: Lặp đơn (Simple Fixed-Point Iteration) ---
    function [root, iter] = simpleIterationMethod(app, g, x0, tol)
        iter = 0;
        maxIter = 1000; 
        x_current = x0;
        
        while iter < maxIter
            iter = iter + 1;
            x_next = g(x_current);
            
            % Điều kiện dừng: Sai số tương đối
            if abs(x_next - x_current) < tol
                root = x_next;
                return;
            end
            
            x_current = x_next;
    
            % Kiểm tra điều kiện hội tụ (Ví dụ: x_next không quá lớn)
            if isnan(x_next) || isinf(x_next)
                uialert(app.UIFigure, 'Lặp đơn: Hàm g(x) phân kỳ. Nghiệm không hội tụ.', 'Lỗi thuật toán');
                root = NaN;
                return;
            end
        end
        
        root = x_current;
        if iter >= maxIter
            uialert(app.UIFigure, 'Lặp đơn: Không hội tụ trong số lần lặp tối đa.', 'Cảnh báo');
        end
    end
    % --- Hàm hỗ trợ: Vẽ đồ thị ---
    function plotFunction(app, f, a_range, b_range, root)
        % Tạo vector x cho khoảng vẽ
        range_min = min(a_range, b_range) - 0.5;
        range_max = max(a_range, b_range) + 0.5;
        x = linspace(range_min, range_max, 200); 
        y = f(x);
        
        % Xóa đồ thị cũ
        cla(app.UIAxes); 
        
        % Vẽ hàm số
        plot(app.UIAxes, x, y, 'LineWidth', 2, 'DisplayName', 'f(x)');
        
        % Giữ đồ thị để vẽ thêm
        hold(app.UIAxes, 'on'); 

        % Vẽ trục x (y=0)
        yline(app.UIAxes, 0, '--k', 'DisplayName', 'Trục X');
        
        % Đánh dấu nghiệm (nếu có)
        if ~isnan(root)
            plot(app.UIAxes, root, 0, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'DisplayName', 'Nghiệm');
        end
        
        hold(app.UIAxes, 'off');
        
        % Thiết lập nhãn và tiêu đề
        app.UIAxes.XLabel.String = 'X';
        app.UIAxes.YLabel.String = 'Y';
        title(app.UIAxes, 'Đồ thị hàm số');
        grid(app.UIAxes, 'on');
        legend(app.UIAxes, 'Location', 'best');
    end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                app.XData = str2double(app.NhapX.Value);
                app.YData = str2double(app.NhapY.Value);
                app.InterpPoint = app.Nhapgiatrinoisuy.Value;
                if isempty(app.XData) || isempty(app.YData) || length(app.XData) ~= length(app.YData)
                    uialert(app.UIFigure, 'Dữ liệu X và Y không hợp lệ hoặc không cùng kích thước.', 'Lỗi Dữ liệu');
                    return;
                end
                if isempty(app.InterpPoint) || numel(app.InterpPoint) > 1
                    uialert(app.UIFigure, 'Giá trị cần nội suy phải là một số duy nhất.', 'Lỗi Dữ liệu');
                    return;
                end
            catch ME
                uialert(app.UIFigure, 'Lỗi định dạng dữ liệu X hoặc Y. Đảm bảo bạn nhập các số cách nhau bằng dấu cách.', 'Lỗi Đầu vào');
                return;
            end
            switch app.InterpMethod
                case 'Lagrange'
                    app.InterpResult = NoiSuyLagrange(app.XData, app.YData, app.InterpPoint);
                case 'Newton'
                    app.InterpResult = NoiSuyNewton(app.XData, app.YData, app.InterpPoint);
            end
            app.Ketquanoisuy.Value = app.InterpResult;
            x_plot = linspace(min(app.XData), max(app.XData), 100);
            if strcmp(app.InterpMethod, 'Lagrange')
                y_plot = arrayfun(@(x) NoiSuyLagrange(app.XData, app.YData, x), x_plot);
            elseif strcmp(app.InterpMethod, 'Newton')
                y_plot = arrayfun(@(x) NoiSuyNewton(app.XData, app.YData, x), x_plot);
            end
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.XData, app.YData, 'o', 'DisplayName', 'Dữ liệu ban đầu', 'MarkerFaceColor', 'b');
            plot(app.UIAxes, x_plot, y_plot, '-', 'DisplayName', [app.InterpMethod ' Interpolation']);
            plot(app.UIAxes, app.InterpPoint, app.InterpResult, 'xr', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Điểm nội suy');
            title(app.UIAxes, ['Đồ thị nội suy: ' app.InterpMethod]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            legend(app.UIAxes, 'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');  
        end

        % Value changed function: Chonphuongphap
        function ChonphuongphapValueChanged(app, event)
            newMethod = app.Chonphuongphap.Value;
            app.InterMethod = newMethod;
            disp(['Phương pháp đã chọn: ' newMethod]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpXEditFieldLabel = uilabel(app.UIFigure);
            app.NhpXEditFieldLabel.Text = 'Nhập X';
            app.NhapX = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'NỘI SUY'};
            app.NhpYEditFieldLabel = uilabel(app.UIFigure);
            app.NhpYEditFieldLabel.Text = 'Nhập Y';
            app.NhapY = uieditfield(app.UIFigure, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.Chonphuongphap = uidropdown(app.UIFigure);
            app.Chonphuongphap.Items = {'Lagrange', 'Newton'};
            app.Chonphuongphap.ValueChangedFcn = createCallbackFcn(app, @ChonphuongphapValueChanged, true);
            app.Chonphuongphap.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatrinoisuy = uieditfield(app.UIFigure, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.Ketquanoisuy = uieditfield(app.UIFigure, 'numeric');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiSuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end