classdef Tab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GiiThiuNhmTab                 matlab.ui.container.Tab
        TextArea_20                   matlab.ui.control.TextArea
        TextArea_19                   matlab.ui.control.TextArea
        TextArea_18                   matlab.ui.control.TextArea
        TextArea_17                   matlab.ui.control.TextArea
        TextArea_16                   matlab.ui.control.TextArea
        TextArea_15                   matlab.ui.control.TextArea
        TextArea_14                   matlab.ui.control.TextArea
        TextArea_13                   matlab.ui.control.TextArea
        TextArea_12                   matlab.ui.control.TextArea
        TextArea_11                   matlab.ui.control.TextArea
        TextArea_10                   matlab.ui.control.TextArea
        TextArea_9                    matlab.ui.control.TextArea
        TextArea_8                    matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        TichPhanTab                   matlab.ui.container.Tab
        TnhtchphntbngdliuLabel        matlab.ui.control.Label
        Nhapdulieux                   matlab.ui.control.EditField
        NhpdliuxLabel                 matlab.ui.control.Label
        Nhapdulieuy                   matlab.ui.control.EditField
        NhpdliuyLabel                 matlab.ui.control.Label
        KetQua                        matlab.ui.control.NumericEditField
        KtquEditFieldLabel            matlab.ui.control.Label
        NhapN                         matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        Can1                          matlab.ui.control.NumericEditField
        NhpcnEditFieldLabel           matlab.ui.control.Label
        Phuongphaptichphan            matlab.ui.control.DropDown
        PhngphptchphnDropDownLabel    matlab.ui.control.Label
        Nhaphamso                     matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        Can2                          matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        DaoHamTab                     matlab.ui.container.Tab
        vectoryEditField              matlab.ui.control.EditField
        vectoryEditFieldLabel         matlab.ui.control.Label
        vectorxEditField              matlab.ui.control.EditField
        vectorxEditFieldLabel         matlab.ui.control.Label
        NhpgitrchobngdliuLabel        matlab.ui.control.Label
        chongiatrisaiso               matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel      matlab.ui.control.Label
        KetQua_2                      matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2          matlab.ui.control.Label
        buocnhay                      matlab.ui.control.NumericEditField
        BcnhyhLabel                   matlab.ui.control.Label
        NhapX                         matlab.ui.control.NumericEditField
        NhpimxLabel                   matlab.ui.control.Label
        phuongphapdaoham              matlab.ui.control.DropDown
        PhngPhpoHmLabel               matlab.ui.control.Label
        Nhaphamso_2                   matlab.ui.control.EditField
        NhphmsEditFieldLabel_2        matlab.ui.control.Label
        RunButton_2                   matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        TimNghiemTab                  matlab.ui.container.Tab
        Nhaphamgx                     matlab.ui.control.EditField
        NhphmgxchophngphplpEditFieldLabel  matlab.ui.control.Label
        Nhapdaoham                    matlab.ui.control.EditField
        NhpohmchophngphptuyntnhEditFieldLabel  matlab.ui.control.Label
        Solanlap                      matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        Ketqua                        matlab.ui.control.NumericEditField
        KtqunghimLabel                matlab.ui.control.Label
        Chonphuongphaptinh            matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel     matlab.ui.control.Label
        Nhapsaiso                     matlab.ui.control.NumericEditField
        NhpsaischophpLabel            matlab.ui.control.Label
        NhapA                         matlab.ui.control.NumericEditField
        NhpkhongphnlinghimLabel       matlab.ui.control.Label
        Nhapphuongtrinh               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel     matlab.ui.control.Label
        RUN                           matlab.ui.control.Button
        NhapB                         matlab.ui.control.NumericEditField
        TextArea_6                    matlab.ui.control.TextArea
        UIAxes_3                      matlab.ui.control.UIAxes
        NoiSuyTab                     matlab.ui.container.Tab
        Ketquanoisuy                  matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        Nhapgiatrinoisuy              matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        Chonphuongphap                matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapY                         matlab.ui.control.EditField
        NhpYEditFieldLabel            matlab.ui.control.Label
        NhapX_2                       matlab.ui.control.EditField
        NhpXEditFieldLabel            matlab.ui.control.Label
        TextArea_7                    matlab.ui.control.TextArea
        RUNButton                     matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
        HoiQuyTab                     matlab.ui.container.Tab
        RunButton_3                   matlab.ui.control.Button
        KetquadudoanTextArea          matlab.ui.control.TextArea
        KtqudonLabel                  matlab.ui.control.Label
        GiatridudoanTextArea          matlab.ui.control.TextArea
        GitrdonhstngquanLabel         matlab.ui.control.Label
        KetquaphuongtrinhTextArea     matlab.ui.control.TextArea
        KtquphngtrnhLabel             matlab.ui.control.Label
        NhapYTextArea                 matlab.ui.control.TextArea
        NhpYTextAreaLabel             matlab.ui.control.Label
        NhapXText                     matlab.ui.control.TextArea
        NhpXTextAreaLabel             matlab.ui.control.Label
        TextArea_5                    matlab.ui.control.TextArea
        PhuongphapDropDown            matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        UIAxes2_HoiQuy                matlab.ui.control.UIAxes
    end


   methods (Access = private)
    % --- Hàm hỗ trợ: Vẽ đồ thị ---

function plotFunction(app, f, a_range, b_range, root)
% Tạo vector x cho khoảng vẽ
range_min = min(a_range, b_range) - 0.5;
range_max = max(a_range, b_range) + 0.5;
x = linspace(range_min, range_max, 200);
y = f(x);
% Xóa đồ thị cũ
cla(app.UIAxes_3);
% Vẽ hàm số
plot(app.UIAxes_3, x, y, 'LineWidth', 2, 'DisplayName', 'f(x)');
% Giữ đồ thị để vẽ thêm
hold(app.UIAxes_3, 'on');
% Vẽ trục x (y=0)
yline(app.UIAxes_3, 0, '--k', 'DisplayName', 'Trục X');
% Đánh dấu nghiệm (nếu có)
if ~isnan(root)
plot(app.UIAxes_3, root, 0, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'DisplayName', 'Nghiệm');
end
hold(app.UIAxes_3, 'off');
% Thiết lập nhãn và tiêu đề
app.UIAxes_3.XLabel.String = 'X';
app.UIAxes_3.YLabel.String = 'Y';
title(app.UIAxes_3, 'Đồ thị hàm số');
grid(app.UIAxes_3, 'on');
legend(app.UIAxes_3, 'Location', 'best');
end
end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunTichPhan(app, event)
    try
    %% 1. LẤY DỮ LIỆU
    % Lấy chuỗi từ các ô nhập liệu
    strHam = string(app.Nhaphamso.Value); 
    strDataX = string(app.Nhapdulieux.Value);
    strDataY = string(app.Nhapdulieuy.Value);
    
    % Lấy phương pháp
    method = string(app.Phuongphaptichphan.Value);
    
    % Khai báo biến chung
    y = []; 
    h = 0; 
    N = 0;

    %% 2. XỬ LÝ DỮ LIỆU ĐẦU VÀO
    % Kiểm tra: Người dùng nhập Hàm số hay Nhập dữ liệu X,Y?
    
    if strHam ~= ""  
        % --- TRƯỜNG HỢP A: CÓ NHẬP HÀM SỐ ---
        
        % Lấy cận và N
        a = double(app.Can1.Value);
        b = double(app.Can2.Value);
        N = double(app.NhapN.Value);
        
        % Xử lý chuỗi hàm (thêm dấu chấm để tính toán vector)
        strHam = strrep(strHam, '^', '.^');
        strHam = strrep(strHam, '*', '.*');
        strHam = strrep(strHam, '/', './');
        
        % Tạo function handle
        try
            func_str = ['@(x) ' char(strHam)];
            f = str2func(func_str);
        catch
            uialert(app.UIFigure, 'Hàm số nhập lỗi cú pháp!', 'Lỗi');
            return;
        end
        
        % Tính x, y, h theo công thức
        h = (b - a) / N;
        x = a:h:b;      
        y = f(x); 
        
    elseif strDataX ~= "" && strDataY ~= ""
        % --- TRƯỜNG HỢP B: NHẬP DỮ LIỆU RỜI RẠC X, Y ---
        
        % Chuyển chuỗi thành mảng số
        x = str2num(strDataX); 
        y = str2num(strDataY);
        
        % Kiểm tra lỗi dữ liệu cơ bản
        if isempty(x) || isempty(y)
            uialert(app.UIFigure, 'Dữ liệu x hoặc y không hợp lệ!', 'Lỗi Nhập Liệu'); 
            return;
        end
        if length(x) ~= length(y)
            uialert(app.UIFigure, 'Số lượng phần tử x và y phải bằng nhau!', 'Lỗi Kích Thước'); 
            return;
        end
        
        % Tự tính h và N từ dữ liệu
        h = x(2) - x(1); 
        N = length(x) - 1; 
        
    else
        % --- TRƯỜNG HỢP C: KHÔNG NHẬP GÌ CẢ ---
        uialert(app.UIFigure, 'Vui lòng nhập "Hàm số" HOẶC "Dữ liệu x,y"!', 'Thiếu dữ liệu');
        return;
    end

    %% 3. TÍNH TOÁN TÍCH PHÂN (Dùng chung cho cả 2 trường hợp)
    KetQua = 0; 
    
    if contains(method, 'thang', 'IgnoreCase', true) 
        % Phương pháp Hình Thang
        KetQua = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end));
        
    elseif contains(method, '1/3') 
        % Phương pháp Simpson 1/3
        if mod(N, 2) ~= 0
            uialert(app.UIFigure, 'Simpson 1/3 yêu cầu số khoảng N chẵn (Số điểm lẻ)!', 'Lỗi N');
            return;
        end
        sum_le = sum(y(2:2:end-1));  
        sum_chan = sum(y(3:2:end-2)); 
        KetQua = (h / 3) * (y(1) + 4 * sum_le + 2 * sum_chan + y(end));
        
    elseif contains(method, '3/8')
        % Phương pháp Simpson 3/8
        if mod(N, 3) ~= 0
            uialert(app.UIFigure, 'Simpson 3/8 yêu cầu số khoảng N chia hết cho 3!', 'Lỗi N');
            return;
        end
        weights = 3 * ones(1, length(y));
        weights(1) = 1; weights(end) = 1;
        weights(4:3:end-1) = 2;
        KetQua = (3 * h / 8) * sum(weights .* y);
        
    else
        uialert(app.UIFigure, ['Chưa chọn phương pháp hợp lệ: ' char(method)], 'Lỗi');
        return;
    end
    
    %% 4. HIỂN THỊ KẾT QUẢ
    % Gán giá trị vào ô kết quả
    try
        app.KetQua.Value = KetQua; 
    catch
        app.KetQua.Value = num2str(KetQua); 
    end
    
catch e
    % Bắt lỗi hệ thống
    uialert(app.UIFigure, e.message, 'Lỗi Hệ Thống');
end
        end

        % Button pushed function: RunButton_2
        function RunDaoHam(app, event)
 try
        %% ==== LẤY DỮ LIỆU CHUNG ====
        strHam = strtrim(string(app.Nhaphamso_2.Value));
        method = string(app.phuongphapdaoham.Value);

        %% =====================================================
        %% 1. TRƯỜNG HỢP A: ĐẠO HÀM HÀM SỐ
        %% =====================================================
        if strHam ~= ""
            x0 = app.NhapX.Value;
            h  = app.buocnhay.Value;
            order = string(app.chongiatrisaiso.Value);

            if h == 0
                error('Bước nhảy h phải khác 0');
            end

            % Chuẩn hóa toán tử
            strHam = strrep(strHam,'^','.^');
            strHam = strrep(strHam,'*','.*');
            strHam = strrep(strHam,'/','./');

            % Tạo hàm
            f = str2func(['@(x)' char(strHam)]);
            f(x0); % test lỗi cú pháp

            switch method
                case 'Newton tiến'
                    kq = tinh_newton_tien(f, x0, h, order);
                case 'Newton lùi'
                    kq = tinh_newton_lui(f, x0, h, order);
                case 'Newton trung tâm'
                    kq = tinh_trung_tam(f, x0, h, order);
                otherwise
                    error('Chưa chọn phương pháp đạo hàm');
            end

            app.KetQua_2.Value = kq;
            return;
        end

        %% =====================================================
        %% 2. TRƯỜNG HỢP B: ĐẠO HÀM BẢNG SỐ LIỆU (VECTOR)
        %% =====================================================
        x = str2num(app.vectorxEditField.Value); %#ok<ST2NM>
        y = str2num(app.vectoryEditField.Value); %#ok<ST2NM>
        i = round(app.NhapX.Value);   % dùng ô NhapX làm chỉ số i

        if isempty(x) || isempty(y)
            error('Vui lòng nhập vector x và vector y');
        end
        if length(x) ~= length(y)
            error('Vector x và y phải cùng số phần tử');
        end
        if length(x) < 3
            error('Cần ít nhất 3 điểm dữ liệu');
        end
        if i < 2 || i > length(x)-1
            error('Chỉ số i phải thỏa mãn: 2 ≤ i ≤ n-1');
        end

        h = x(2) - x(1); % bước nhảy từ bảng

        switch method
            case 'Newton tiến'
                kq = (y(i+1) - y(i)) / h;
            case 'Newton lùi'
                kq = (y(i) - y(i-1)) / h;
            case 'Newton trung tâm'
                kq = (y(i+1) - y(i-1)) / (2*h);
        end

        app.KetQua_2.Value = kq;

    catch e
        uialert(app.UIFigure, e.message, 'Lỗi Đạo Hàm');
    end
        end

        % Button pushed function: RunButton_3
        function RunHoiQuy(app, event)
            lines_1 = app.NhapXText.Value;
            lines_2 = app.NhapYTextArea.Value;
            lines_3 = app.GiatridudoanTextArea.Value;
            %ghép thành chuỗi
            matrix_str_1 = strjoin(lines_1, ''); %dạng "1 2 3 4 5 6"
            matrix_str_2 = strjoin(lines_2, '');
            matrix_str_3 = strjoin(lines_3, '');

            %chuyển chuỗi thành ma trận
            X = str2num(matrix_str_1);
            Y = str2num(matrix_str_2);
            %chuyển thành số
            Z = str2num(matrix_str_3)
            % Kiểm tra hợp lệ
            if isempty(X)
                uialert(app.UIFigure, "Bạn nhập ma trận không đúng dạng!, Cú pháp là 1 2 3 4,...", "Lỗi ma trận X");
                return;
            end
            if isempty(Y)
                uialert(app.UIFigure, "Bạn nhập ma trận không đúng dạng!,Cú pháp là 1 2 3 4,...", "Lỗi ma trận Y");
                return;
            end
            if(length(X)~=length(Y))
                uialert(app.UIFigure, "kích thước 2 ma trận không bằng nhau, vui lòng nhập lại!","Lỗi kích thước!");
                return;
            end
            % xử lý các trường hợp tính hồi quy bằng dropdown
            loai = app.PhuongphapDropDown.Value;
            %TH1: Tuyến tính
            switch loai
                case 'Tuyến tính'
                    [a1, a0, r2] = HoiQuyTuyenTinh(X,Y);
                    Y_fit = a0 + a1.*X;
                    ketquadudoan = a0+a1*Z;
                    app.KetquaphuongtrinhTextArea.Value = sprintf("y = %.4f + %.4f*x", a0, a1);
                    app.KetquadudoanTextArea.Value = sprintf("y_dudoan = %.4f", ketquadudoan);
                    plot(app.UIAxes2_HoiQuy,X, Y_fit);
                    xlabel(app.UIAxes2_HoiQuy,"X");
                    ylabel(app.UIAxes2_HoiQuy,"Y");
                    title(app.UIAxes2_HoiQuy, "Biểu đồ hồi quy tuyến tính");
            %TH2: Hàm mũ
                case 'Hàm mũ'
                    [a, b, r2] = axmub(X,Y);
                    Y_fit = a .* exp(b .* X);
                    ketquadudoan = a*exp(b*Z);
                    app.KetquaphuongtrinhTextArea.Value = sprintf("y = %.4f*e^(%.4f*x)", a, b);
                    app.KetquadudoanTextArea.Value = sprintf("y_ketquadudoan = %.4f", ketquadudoan);
                    plot(app.UIAxes2_HoiQuy, X, Y_fit);
                    xlabel(app.UIAxes2_HoiQuy,"X");
                    ylabel(app.UIAxes2_HoiQuy,"Y");
                    title(app.UIAxes2_HoiQuy, "Biểu đồ hồi quy hàm mũ");
            %TH3: Hàm Logarit
               case 'Logarit'
                    [a, b, r2] = hoiquylogarit(X, Y);
                    Y_fit = a .* (X .^ b);   % y = a * x^b
                    ketquadudoan = a.*(Z.^b);
                    app.KetquaphuongtrinhTextArea.Value = sprintf("y = %.4f * x^(%.4f)", a, b);
                    app.KetquadudoanTextArea.Value = sprintf("y_dudoan = %.4f", ketquadudoan);
                    plot(app.UIAxes2_HoiQuy, X, Y_fit);
                    xlabel(app.UIAxes2_HoiQuy, "X");
                    ylabel(app.UIAxes2_HoiQuy, "Y");
                    title(app.UIAxes2_HoiQuy, "Hồi quy dạng lũy thừa");
            end
        end

        % Button pushed function: RUN
        function RunTimNghiem(app, event)
        try
        %% 1. LẤY DỮ LIỆU
        % Lấy chuỗi phương trình f(x)
        strHam = string(app.Nhapphuongtrinh.Value); 
        
        % Lấy số liệu (xử lý an toàn)
        a = double(app.NhapA.Value); 
        b = double(app.NhapB.Value); 
        tolerance = double(app.Nhapsaiso.Value);
        
        % Lấy chuỗi Đạo hàm và Hàm g(x)
        strHamPrime = string(app.Nhapdaoham.Value);
        strHamG = string(app.Nhaphamgx.Value);
        
        % Lấy phương pháp từ DropDown
        method = string(app.Chonphuongphaptinh.Value);
        
        %% 2. TẠO HÀM SỐ
        % Tự động thêm dấu chấm (.) cho phép toán vector
        strHam = strrep(strHam, '^', '.^'); strHam = strrep(strHam, '*', '.*'); strHam = strrep(strHam, '/', './');
        strHamPrime = strrep(strHamPrime, '^', '.^'); strHamPrime = strrep(strHamPrime, '*', '.*'); strHamPrime = strrep(strHamPrime, '/', './');
        strHamG = strrep(strHamG, '^', '.^'); strHamG = strrep(strHamG, '*', '.*'); strHamG = strrep(strHamG, '/', './');
        
        % Tạo function handle
        try
            f = str2func(['@(x) ' char(strHam)]);
            f_prime = str2func(['@(x) ' char(strHamPrime)]);
            f_g = str2func(['@(x) ' char(strHamG)]); 
        catch
            uialert(app.UIFigure, 'Hàm số, Đạo hàm, hoặc Hàm g(x) nhập lỗi cú pháp!', 'Lỗi');
            return;
        end
        
        %% 3. TÍNH TOÁN (Gọi các hàm giải thuật riêng)
        root = NaN;
        iterations = 0;
        
        % --- SỬ DỤNG CONTAINS ĐỂ SO SÁNH PHƯƠNG PHÁP ---
        if contains(method, 'chia đôi', 'IgnoreCase', true) 
            
            % Kiểm tra điều kiện Chia đôi
            fa = f(a);
            fb = f(b);
            if fa * fb > 0
                uialert(app.UIFigure, 'Chia đôi: f(a) và f(b) phải trái dấu!', 'Lỗi Điều kiện');
                return;
            end
            [root, iterations] = bisectionMethod(app, f, a, b, tolerance);
            
        elseif contains(method, 'tiếp tuyến', 'IgnoreCase', true) || contains(method, 'newton', 'IgnoreCase', true)
            
            initial_guess = (a + b) / 2; % Nghiệm xấp xỉ ban đầu x0
            if isempty(char(strHamPrime))
                 uialert(app.UIFigure, 'Phương pháp Tiếp tuyến yêu cầu nhập Đạo hàm f''(x).', 'Thiếu dữ liệu');
                 return;
            end
            [root, iterations] = newtonRaphsonMethod(app, f, f_prime, initial_guess, tolerance);
            
        elseif contains(method, 'lặp', 'IgnoreCase', true)
            
            initial_guess = (a + b) / 2; % Nghiệm xấp xỉ ban đầu x0
             if isempty(char(strHamG))
                uialert(app.UIFigure, 'Phương pháp Lặp đơn yêu cầu nhập Hàm lặp g(x).', 'Thiếu dữ liệu');
                return;
            end
            [root, iterations] = simpleIterationMethod(app, f_g, initial_guess, tolerance);
            
        else
            uialert(app.UIFigure, ['Chưa chọn đúng phương pháp. Bạn đang chọn: ' char(method)], 'Lỗi DropDown');
            return;
        end
        
        %% 4. HIỂN THỊ KẾT QUẢ VÀ VẼ ĐỒ THỊ
        
        % 4a. Cập nhật kết quả số
        if isnan(root)
             KetQuaStr = 'Lỗi/Không hội tụ';
             SoLanLapStr = num2str(iterations);
        else
             KetQuaStr = sprintf('%.8f', root);
             SoLanLapStr = num2str(iterations);
        end

        % 4b. Tự động nhận diện ô KetQua là số hay chữ để hiển thị
        try
            % Gán giá trị số (nếu KetQua là Numeric Edit Field)
            app.Ketqua.Value = root; 
        catch
            % Gán giá trị chuỗi (nếu Ketqua là Text Edit Field)
            app.Ketqua.Value = KetQuaStr; 
        end
        
        try
            % Gán số lần lặp (nếu Solanlap là Numeric Edit Field)
            app.Solanlap.Value = iterations; 
        catch
            % Gán số lần lặp dưới dạng chuỗi
            app.Solanlap.Value = SoLanLapStr;
        end
        
        % 4c. Vẽ đồ thị
        app.plotFunction(f, a, b, root);
        
    catch e
        % Hiển thị lỗi hệ thống/runtime
        uialert(app.UIFigure, e.message, 'Lỗi Hệ Thống');
    end 
        end

        % Button pushed function: RUNButton
        function RunNoiSuy(app, event)
          try
                % --- 1. LẤY DỮ LIỆU ĐẦU VÀO ---
                % Lấy chuỗi từ ô nhập X và Y
                x_str = app.NhapX_2.Value; 
                y_str = app.NhapY.Value;
                
                % Chuyển chuỗi thành mảng số
                % Lưu ý: str2num xử lý tốt dấu cách và dấu phẩy
                x = str2num(x_str); %#ok<ST2NM>
                y = str2num(y_str); %#ok<ST2NM>
                
                % Ép kiểu thành vector hàng (1xN) để tránh lỗi nhân ma trận
                x = reshape(x, 1, []);
                y = reshape(y, 1, []);

                % Lấy giá trị cần nội suy (x_val)
                % Xử lý linh hoạt cho cả ô nhập Text và Numeric
                raw_val = app.Nhapgiatrinoisuy.Value;
                if ischar(raw_val) || isstring(raw_val)
                    x_val = str2double(raw_val);
                else
                    x_val = double(raw_val);
                end

                % --- 2. KIỂM TRA LỖI NHẬP LIỆU ---
                if isempty(x) || isempty(y)
                    uialert(app.UIFigure, 'Dữ liệu X hoặc Y không hợp lệ. Vui lòng nhập các số cách nhau bằng dấu cách.', 'Lỗi nhập liệu');
                    return;
                end
                
                if length(x) ~= length(y)
                    uialert(app.UIFigure, 'Số lượng phần tử của X và Y phải bằng nhau.', 'Lỗi kích thước');
                    return;
                end
                
                if isnan(x_val)
                    uialert(app.UIFigure, 'Giá trị cần nội suy không hợp lệ.', 'Lỗi dữ liệu');
                    return;
                end

                % --- 3. TÍNH TOÁN THEO PHƯƠNG PHÁP ĐÃ CHỌN ---
                method = app.Chonphuongphap.Value; % Lấy tên phương pháp
                
                if strcmp(method, 'Lagrange')
                    y_res = NoiSuyLagrange(x, y, x_val);
                elseif strcmp(method, 'Newton')
                    y_res = NoiSuyNewton(x, y, x_val);
                else
                    % Mặc định dùng Lagrange nếu không chọn
                    y_res = NoiSuyLagrange(x, y, x_val);
                end

                % --- 4. HIỂN THỊ KẾT QUẢ (ĐÃ SỬA LỖI TẠI ĐÂY) ---
                % Lỗi cũ: app.Ketquanoisuy.Value = num2str(y_res); -> Sai vì ô này chỉ nhận số
                % Sửa lại: Gán trực tiếp giá trị số y_res
                app.Ketquanoisuy.Value = y_res; 

                % --- 5. VẼ ĐỒ THỊ ---
                % Tạo 100 điểm x mịn để vẽ đường cong
                x_plot = linspace(min(x), max(x), 100);
                y_plot = zeros(size(x_plot));
                
                % Tính y_plot tương ứng
                if strcmp(method, 'Lagrange')
                    for k = 1:length(x_plot)
                        y_plot(k) = NoiSuyLagrange(x, y, x_plot(k));
                    end
                else % Newton
                    for k = 1:length(x_plot)
                        y_plot(k) = NoiSuyNewton(x, y, x_plot(k));
                    end
                end
                
                % Vẽ lên trục UIAxes
                cla(app.UIAxes_4); % Xóa đồ thị cũ
                hold(app.UIAxes_4, 'on');
                grid(app.UIAxes_4, 'on');
                
                % Vẽ các điểm dữ liệu gốc (màu xanh, hình tròn)
                plot(app.UIAxes_4, x, y, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 6, 'DisplayName', 'Dữ liệu thực');
                
                % Vẽ đường nội suy (màu xanh dương, nét liền)
                plot(app.UIAxes_4, x_plot, y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', ['Nội suy ' method]);
                
                % Vẽ điểm kết quả vừa tính (màu đỏ, hình vuông)
                plot(app.UIAxes_4, x_val, y_res, 'rs', 'MarkerFaceColor', 'r', 'MarkerSize', 8, 'LineWidth', 1.5, 'DisplayName', 'Điểm nội suy');
                
                % Trang trí
                title(app.UIAxes_4, ['Đồ thị nội suy: ' method]);
                xlabel(app.UIAxes_4, 'X');
                ylabel(app.UIAxes_4, 'Y');
                legend(app.UIAxes_4, 'Location', 'best');
                
                hold(app.UIAxes_4, 'off');

            catch ME
                uialert(app.UIFigure, ['Lỗi thực thi: ' ME.message], 'Lỗi');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiiThiuNhmTab = uitab(app.TabGroup);
            app.GiiThiuNhmTab.Title = 'Giới Thiệu Nhóm';
            app.Image = uiimage(app.GiiThiuNhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-fetel.png');
            app.TextArea_8 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_8.Value = {'GIỚI THIỆU THÀNH VIÊN NHÓM'};
            app.TextArea_9 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_9.Value = {'PHẠM VĂN PHÚC'};
            app.TextArea_10 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_10.Value = {'NGUYỄN TRÀN QUỐC BẢO'};
            app.TextArea_11 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_11.Value = {'HỒ TRỌNG HẢI'};
            app.TextArea_12 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_12.Value = {'NGUYỄN ĐỨC SƠN'};
            app.TextArea_13 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_13.Value = {'NGUYỄN KHÁNH HOÀNG'};
            app.TextArea_14 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_14.Value = {'HỌ VÀ TÊN'};
            app.TextArea_15 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_15.Value = {'23207096'};
            app.TextArea_16 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_16.Value = {'MSSV'};
            app.TextArea_17 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_17.Value = {'23207038'};
            app.TextArea_18 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_18.Value = {'23207050'};
            app.TextArea_19 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_19.Value = {'23207100'};
            app.TextArea_20 = uitextarea(app.GiiThiuNhmTab);
            app.TextArea_20.Value = {'23207057'};
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'TichPhan';
            app.TextArea = uitextarea(app.TichPhanTab);
            app.TextArea.Value = {'TÍCH PHÂN'};
            app.Can2 = uieditfield(app.TichPhanTab, 'numeric');
            app.RunButton = uibutton(app.TichPhanTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunTichPhan, true);
            app.RunButton.Text = 'Run';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Nhaphamso = uieditfield(app.TichPhanTab, 'text');
            app.PhngphptchphnDropDownLabel = uilabel(app.TichPhanTab);
            app.PhngphptchphnDropDownLabel.Text = 'Phương pháp tích phân';
            app.Phuongphaptichphan = uidropdown(app.TichPhanTab);
            app.Phuongphaptichphan.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.Phuongphaptichphan.Value = 'Hình thang';
            app.NhpcnEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpcnEditFieldLabel.Text = 'Nhập cận';
            app.Can1 = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapN = uieditfield(app.TichPhanTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.TichPhanTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQua = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpdliuyLabel = uilabel(app.TichPhanTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.Nhapdulieuy = uieditfield(app.TichPhanTab, 'text');
            app.NhpdliuxLabel = uilabel(app.TichPhanTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.Nhapdulieux = uieditfield(app.TichPhanTab, 'text');
            app.TnhtchphntbngdliuLabel = uilabel(app.TichPhanTab);
            app.TnhtchphntbngdliuLabel.Text = 'Tính tích phân từ bảng dữ liệu';
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'DaoHam';
            app.TextArea_2 = uitextarea(app.DaoHamTab);
            app.TextArea_2.Value = {'Đạo Hàm'};
            app.RunButton_2 = uibutton(app.DaoHamTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunDaoHam, true);
            app.RunButton_2.Text = 'Run';
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaoHamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Nhaphamso_2 = uieditfield(app.DaoHamTab, 'text');
            app.PhngPhpoHmLabel = uilabel(app.DaoHamTab);
            app.PhngPhpoHmLabel.Text = 'Phương Pháp Đạo Hàm';
            app.phuongphapdaoham = uidropdown(app.DaoHamTab);
            app.phuongphapdaoham.Items = {'Newton tiến', 'Newton lùi', 'Newton trung tâm'};
            app.phuongphapdaoham.Value = 'Newton tiến';
            app.NhpimxLabel = uilabel(app.DaoHamTab);
            app.NhpimxLabel.Text = 'Nhập giá trị cần tính';
            app.NhapX = uieditfield(app.DaoHamTab, 'numeric');
            app.BcnhyhLabel = uilabel(app.DaoHamTab);
            app.BcnhyhLabel.Text = 'Bước nhảy (h)';
            app.buocnhay = uieditfield(app.DaoHamTab, 'numeric');
            app.KtquEditFieldLabel_2 = uilabel(app.DaoHamTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQua_2 = uieditfield(app.DaoHamTab, 'numeric');
            app.ChngitrsaisDropDownLabel = uilabel(app.DaoHamTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.chongiatrisaiso = uidropdown(app.DaoHamTab);
            app.chongiatrisaiso.Items = {'O(h)', 'O(h^2)'};
            app.chongiatrisaiso.Value = 'O(h)';
            app.NhpgitrchobngdliuLabel = uilabel(app.DaoHamTab);
            app.NhpgitrchobngdliuLabel.Text = 'Nhập giá trị cho bảng dữ liệu';
            app.vectorxEditFieldLabel = uilabel(app.DaoHamTab);
            app.vectorxEditFieldLabel.Text = 'vector x';
            app.vectorxEditField = uieditfield(app.DaoHamTab, 'text');
            app.vectoryEditFieldLabel = uilabel(app.DaoHamTab);
            app.vectoryEditFieldLabel.Text = 'vector y';
            app.vectoryEditField = uieditfield(app.DaoHamTab, 'text');
            app.TimNghiemTab = uitab(app.TabGroup);
            app.TimNghiemTab.Title = 'TimNghiem';
            app.UIAxes_3 = uiaxes(app.TimNghiemTab);
            title(app.UIAxes_3, 'Đồ thị hàm số')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TextArea_6 = uitextarea(app.TimNghiemTab);
            app.TextArea_6.Value = {'TÌM NGHIỆM'};
            app.NhapB = uieditfield(app.TimNghiemTab, 'numeric');
            app.RUN = uibutton(app.TimNghiemTab, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RunTimNghiem, true);
            app.RUN.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TimNghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình:';
            app.Nhapphuongtrinh = uieditfield(app.TimNghiemTab, 'text');
            app.NhpkhongphnlinghimLabel = uilabel(app.TimNghiemTab);
            app.NhpkhongphnlinghimLabel.Text = 'Nhập khoảng phân li nghiệm:';
            app.NhapA = uieditfield(app.TimNghiemTab, 'numeric');
            app.NhpsaischophpLabel = uilabel(app.TimNghiemTab);
            app.NhpsaischophpLabel.Text = 'Nhập sai số cho phép:';
            app.Nhapsaiso = uieditfield(app.TimNghiemTab, 'numeric');
            app.ChnphngphptmDropDownLabel = uilabel(app.TimNghiemTab);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm:';
            app.Chonphuongphaptinh = uidropdown(app.TimNghiemTab);
            app.Chonphuongphaptinh.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến'};
            app.Chonphuongphaptinh.Value = 'Chia đôi';
            app.KtqunghimLabel = uilabel(app.TimNghiemTab);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm:';
            app.Ketqua = uieditfield(app.TimNghiemTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.TimNghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp:';
            app.Solanlap = uieditfield(app.TimNghiemTab, 'numeric');
            app.NhpohmchophngphptuyntnhEditFieldLabel = uilabel(app.TimNghiemTab);
            app.NhpohmchophngphptuyntnhEditFieldLabel.Text = 'Nhập đạo hàm cho phương pháp tuyến tính';
            app.Nhapdaoham = uieditfield(app.TimNghiemTab, 'text');
            app.NhphmgxchophngphplpEditFieldLabel = uilabel(app.TimNghiemTab);
            app.NhphmgxchophngphplpEditFieldLabel.Text = 'Nhập hàm gx cho phương pháp lặp';
            app.Nhaphamgx = uieditfield(app.TimNghiemTab, 'text');
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'NoiSuy';
            app.UIAxes_4 = uiaxes(app.NoiSuyTab);
            title(app.UIAxes_4, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.RUNButton = uibutton(app.NoiSuyTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RunNoiSuy, true);
            app.RUNButton.Text = 'RUN';
            app.TextArea_7 = uitextarea(app.NoiSuyTab);
            app.TextArea_7.Value = {'NỘI SUY'};
            app.NhpXEditFieldLabel = uilabel(app.NoiSuyTab);
            app.NhpXEditFieldLabel.Text = 'Nhập X';
            app.NhapX_2 = uieditfield(app.NoiSuyTab, 'text');
            app.NhpYEditFieldLabel = uilabel(app.NoiSuyTab);
            app.NhpYEditFieldLabel.Text = 'Nhập Y';
            app.NhapY = uieditfield(app.NoiSuyTab, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.NoiSuyTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.Chonphuongphap = uidropdown(app.NoiSuyTab);
            app.Chonphuongphap.Items = {'Lagrange', 'Newton'};
            app.Chonphuongphap.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NoiSuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatrinoisuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NoiSuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.Ketquanoisuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'HoiQuy';
            app.UIAxes2_HoiQuy = uiaxes(app.HoiQuyTab);
            title(app.UIAxes2_HoiQuy, 'Đồ thị biểu diễn ')
            xlabel(app.UIAxes2_HoiQuy, 'X')
            ylabel(app.UIAxes2_HoiQuy, 'Y')
            zlabel(app.UIAxes2_HoiQuy, 'Z')
            app.PhngphpDropDownLabel = uilabel(app.HoiQuyTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhuongphapDropDown = uidropdown(app.HoiQuyTab);
            app.PhuongphapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PhuongphapDropDown.Value = 'Tuyến tính';
            app.TextArea_5 = uitextarea(app.HoiQuyTab);
            app.TextArea_5.Value = {'HỒI QUY'};
            app.NhpXTextAreaLabel = uilabel(app.HoiQuyTab);
            app.NhpXTextAreaLabel.Text = 'Nhập X';
            app.NhapXText = uitextarea(app.HoiQuyTab);
            app.NhpYTextAreaLabel = uilabel(app.HoiQuyTab);
            app.NhpYTextAreaLabel.Text = 'Nhập Y';
            app.NhapYTextArea = uitextarea(app.HoiQuyTab);
            app.KtquphngtrnhLabel = uilabel(app.HoiQuyTab);
            app.KtquphngtrnhLabel.Text = {'Kết quả '; 'phương trình'};
            app.KetquaphuongtrinhTextArea = uitextarea(app.HoiQuyTab);
            app.GitrdonhstngquanLabel = uilabel(app.HoiQuyTab);
            app.GitrdonhstngquanLabel.Text = 'Nhập giá trị dự đoán';
            app.GiatridudoanTextArea = uitextarea(app.HoiQuyTab);
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = {'Kết quả '; 'dự đoán'};
            app.KetquadudoanTextArea = uitextarea(app.HoiQuyTab);
            app.RunButton_3 = uibutton(app.HoiQuyTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunHoiQuy, true);
            app.RunButton_3.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end