classdef TichPhan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KetQua                      matlab.ui.control.NumericEditField
        KtquEditFieldLabel          matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        NhapN                       matlab.ui.control.NumericEditField
        NhpNEditFieldLabel          matlab.ui.control.Label
        Can2                        matlab.ui.control.NumericEditField
        Can1                        matlab.ui.control.NumericEditField
        NhpcnEditFieldLabel         matlab.ui.control.Label
        Phuongphaptichphan          matlab.ui.control.DropDown
        PhngphptchphnDropDownLabel  matlab.ui.control.Label
        Nhaphamso                   matlab.ui.control.EditField
        NhphmsEditFieldLabel        matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

    try
        %% 1. LẤY DỮ LIỆU
        % Lấy chuỗi hàm số
        strHam = string(app.Nhaphamso.Value); 
        
        % Lấy số liệu (xử lý an toàn dù nhập chữ hay số)
        a = double(app.Can1.Value);
        b = double(app.Can2.Value);
        N = double(app.NhapN.Value);
        
        % Lấy phương pháp từ DropDown
        method = string(app.Phuongphaptichphan.Value);

        %% 2. TẠO HÀM SỐ
        % Tự động thêm dấu chấm (.) cho phép toán vector
        strHam = strrep(strHam, '^', '.^');
        strHam = strrep(strHam, '*', '.*');
        strHam = strrep(strHam, '/', './');
        
        % Tạo function handle
        try
            func_str = ['@(x) ' char(strHam)];
            f = str2func(func_str);
        catch
            uialert(app.UIFigure, 'Hàm số nhập lỗi cú pháp!', 'Lỗi');
            return;
        end

        %% 3. TÍNH TOÁN (Tích hợp công thức trực tiếp)
        h = (b - a) / N;
        x = a:h:b;      
        y = f(x);       
        
        KetQua = 0; % Giá trị mặc định
        
        % --- SỬ DỤNG CONTAINS ĐỂ SO SÁNH (An toàn hơn strcmp) ---
        if contains(method, 'thang', 'IgnoreCase', true) 
            % Nếu tên phương pháp có chữ "thang" (bất kể hoa thường/dấu)
            KetQua = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end));
            
        elseif contains(method, '1/3') 
            % Nếu tên phương pháp có chữ "1/3"
            if mod(N, 2) ~= 0
                uialert(app.UIFigure, 'Simpson 1/3 yêu cầu N chẵn!', 'Lỗi N');
                return;
            end
            sum_le = sum(y(2:2:end-1));  
            sum_chan = sum(y(3:2:end-2)); 
            KetQua = (h / 3) * (y(1) + 4 * sum_le + 2 * sum_chan + y(end));
            
        elseif contains(method, '3/8')
            % Nếu tên phương pháp có chữ "3/8"
            if mod(N, 3) ~= 0
                uialert(app.UIFigure, 'Simpson 3/8 yêu cầu N chia hết cho 3!', 'Lỗi N');
                return;
            end
            weights = 3 * ones(1, length(y));
            weights(1) = 1; weights(end) = 1;
            weights(4:3:end-1) = 2;
            KetQua = (3 * h / 8) * sum(weights .* y);
            
        else
            uialert(app.UIFigure, ['Chưa chọn đúng phương pháp. Bạn đang chọn: ' char(method)], 'Lỗi DropDown');
            return;
        end
        
        %% 4. HIỂN THỊ KẾT QUẢ
        % Tự động nhận diện ô KetQua là số hay chữ để hiển thị
        try
            app.KetQua.Value = KetQua; % Nếu ô Kết quả là dạng Số (Numeric Edit Field)
        catch
            app.KetQua.Value = num2str(KetQua); % Nếu ô Kết quả là dạng Chữ (Text Edit Field)
        end
        
    catch e
        uialert(app.UIFigure, e.message, 'Lỗi Hệ Thống');
    end 
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TÍCH PHÂN'};
            app.NhphmsEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Nhaphamso = uieditfield(app.UIFigure, 'text');
            app.PhngphptchphnDropDownLabel = uilabel(app.UIFigure);
            app.PhngphptchphnDropDownLabel.Text = 'Phương pháp tích phân';
            app.Phuongphaptichphan = uidropdown(app.UIFigure);
            app.Phuongphaptichphan.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.Phuongphaptichphan.Value = 'Hình thang';
            app.NhpcnEditFieldLabel = uilabel(app.UIFigure);
            app.NhpcnEditFieldLabel.Text = 'Nhập cận';
            app.Can1 = uieditfield(app.UIFigure, 'numeric');
            app.Can2 = uieditfield(app.UIFigure, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.UIFigure);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapN = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.KtquEditFieldLabel = uilabel(app.UIFigure);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQua = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TichPhan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end