classdef daoham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        chongiatrisaiso           matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel  matlab.ui.control.Label
        KetQua                    matlab.ui.control.NumericEditField
        KtquEditFieldLabel        matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        buocnhay                  matlab.ui.control.NumericEditField
        BcnhyhLabel               matlab.ui.control.Label
        NhapX                     matlab.ui.control.NumericEditField
        NhpimxLabel               matlab.ui.control.Label
        phuongphapdaoham          matlab.ui.control.DropDown
        PhngPhpoHmLabel           matlab.ui.control.Label
        Nhaphamso                 matlab.ui.control.EditField
        NhphmsEditFieldLabel      matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
try
                %% 1. LẤY DỮ LIỆU ĐẦU VÀO
                strHam = string(app.Nhaphamso.Value); 
                x0 = double(app.NhapX.Value);      
                h = double(app.buocnhay.Value);    
                method = string(app.phuongphapdaoham.Value);
                errorOrder = string(app.chongiatrisaiso.Value); 

                %% 2. XỬ LÝ HÀM SỐ
                strHam = strrep(strHam, '^', '.^');
                strHam = strrep(strHam, '*', '.*');
                strHam = strrep(strHam, '/', './');
                
                try
                    func_str = ['@(x) ' char(strHam)];
                    f = str2func(func_str);
                catch
                    uialert(app.UIFigure, 'Hàm số nhập lỗi cú pháp!', 'Lỗi nhập liệu');
                    return;
                end

                %% 3. TÍNH TOÁN (GỌI HÀM NGOÀI)
                KetQuaTinh = 0;

                switch method
                    case 'Newton tiến'
                        % Gọi file tinh_newton_tien.m
                        KetQuaTinh = tinh_newton_tien(f, x0, h, errorOrder);
                        
                    case 'Newton lùi'
                        % Gọi file tinh_newton_lui.m
                        KetQuaTinh = tinh_newton_lui(f, x0, h, errorOrder);
                        
                    case 'Newton trung tâm'
                        % Gọi file tinh_trung_tam.m
                        KetQuaTinh = tinh_trung_tam(f, x0, h, errorOrder);
                        
                    otherwise
                        uialert(app.UIFigure, 'Phương pháp chưa được hỗ trợ!', 'Lỗi');
                        return;
                end

                %% 4. HIỂN THỊ KẾT QUẢ
                app.KetQua.Value = KetQuaTinh;

            catch e
                uialert(app.UIFigure, ['Lỗi chi tiết: ' e.message], 'Lỗi Hệ Thống');
            end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Đạo Hàm'};
            app.NhphmsEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Nhaphamso = uieditfield(app.UIFigure, 'text');
            app.PhngPhpoHmLabel = uilabel(app.UIFigure);
            app.PhngPhpoHmLabel.Text = 'Phương Pháp Đạo Hàm';
            app.phuongphapdaoham = uidropdown(app.UIFigure);
            app.phuongphapdaoham.Items = {'Newton tiến', 'Newton lùi', 'Newton trung tâm'};
            app.phuongphapdaoham.Value = 'Newton tiến';
            app.NhpimxLabel = uilabel(app.UIFigure);
            app.NhpimxLabel.Text = 'Nhập điểm x';
            app.NhapX = uieditfield(app.UIFigure, 'numeric');
            app.BcnhyhLabel = uilabel(app.UIFigure);
            app.BcnhyhLabel.Text = 'Bước nhảy (h)';
            app.buocnhay = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.KtquEditFieldLabel = uilabel(app.UIFigure);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQua = uieditfield(app.UIFigure, 'numeric');
            app.ChngitrsaisDropDownLabel = uilabel(app.UIFigure);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.chongiatrisaiso = uidropdown(app.UIFigure);
            app.chongiatrisaiso.Items = {'O(h)', 'O(h^2)'};
            app.chongiatrisaiso.Value = 'O(h)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = daoham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end